classdef PipelineConfig_dlgBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PipelineConfigurationLabel      matlab.ui.control.Label
        AvailablefunctionsListBoxLabel  matlab.ui.control.Label
        AvailablefunctionsListBox       matlab.ui.control.ListBox
        SelectedfunctionsListBoxLabel   matlab.ui.control.Label
        SelectedfunctionsListBox        matlab.ui.control.ListBox
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        CreatePipelineButton            matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SetOptionsButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        AddfunctiontoDropDownLabel      matlab.ui.control.Label
        AddfunctiontoDropDown           matlab.ui.control.DropDown
        RemoveItemButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Main App
        IdxList % List of indexes from Protocol queryFilter.
        Pipe % Instantiation of PipelineManager class.
    end
    
    methods (Access = private)
        function createFuncList(app)
            root = getenv('Umitoolbox');
            list = dir(fullfile(root, 'Analysis', '\**\*.m'));
            Items = {app.Pipe.FunctionList.Name};
            list_of_files = cellfun(@(x) x(1:end-2), {list.name}, 'UniformOutput',false);
            list_of_folders = {};
            for i = 1:numel(Items)
                idx = strcmp(list_of_files,Items{i});
                str = split(list(idx).folder, filesep);
                list_of_folders(i) =  str(end);
            end
            ItemsData = app.Pipe.FunctionList;
            idx = cellfun(@(x) ~isempty(x), {ItemsData.opts});
            Items(idx) = cellfun(@(x) ['(!) ' x ], Items(idx), 'UniformOutput',false);
            [val,ind] = unique(list_of_folders);
            new_Items = {};
            new_ItemsData = [];
            for i = 1:numel(ind)
             header =['---------- ' val{i} ' ----------'];
             dummyData = ItemsData(1); dummyData.Name = '----------';
             if i < numel(ind)
                 sub_Items = Items(ind(i):ind(i+1)-1);
                 sub_ItemsData = ItemsData(ind(i):ind(i+1)-1);
             else
                 sub_Items = Items(ind(i):end);
                 sub_ItemsData = ItemsData(ind(i):end);
             end
             new_Items = [new_Items, header, sub_Items];
             new_ItemsData = [new_ItemsData, dummyData, sub_ItemsData];
            end
            
            
            app.AvailablefunctionsListBox.Items = new_Items;
            app.AvailablefunctionsListBox.ItemsData = new_ItemsData;
            app.SelectedfunctionsListBox.Items = {};
            app.SelectedfunctionsListBox.ItemsData = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, IdxList)
            
            % Update UIFigure position:
            app.UIFigure.Position = denormalize_UIfig_Position([0.2958 0.2444 0.3682 0.4870]);
            
            app.CallingApp = mainapp;
            app.IdxList = IdxList;
            root = getenv('Umitoolbox');
            app.Pipe = PipelineManager([],app.CallingApp.ProtocolObj, fullfile(root, 'Analysis'));
            app.createFuncList;            
            % ToolTips of buttons
            app.Button_3.Tooltip = 'Moves function up.';
            app.Button_4.Tooltip = 'Moves function down.';
            % Populate AddfunctionDropDown button
            idx = app.CallingApp.ProtocolObj.Idx_Filtered;
            item = cell(1,size(idx,1));
            for i = 1:size(idx,1)
                item{i} = class(app.CallingApp.ProtocolObj.Array.ObjList(idx(i,1)).Array.ObjList(idx(i,2)).Array.ObjList(idx(i,3)));
            end
            Items = [{'Subject', 'Acquisition'}, unique(item)];
            app.AddfunctiontoDropDown.Items = Items;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ConfigureNewPipelineButton.Enable = 'on';
            delete(app)
        end

        % Callback function
        function AddButtonPushed(app, event)
            ItemData_selected = app.AvailablefunctionsListBox.Value;


            if~isempty(ItemData_selected)
                idx = strcmp({app.AvailablefunctionsListBox.ItemsData.funcName}, app.AvailablefunctionsListBox.Value.funcName);
                Item_selected = app.AvailablefunctionsListBox.Items(idx);
                ItemList = app.SelectedfunctionsListBox.Items;
                if ~ismember(Item_selected, ItemList)
                    app.SelectedfunctionsListBox.Items = [app.SelectedfunctionsListBox.Items Item_selected];
                    app.SelectedfunctionsListBox.ItemsData = [app.SelectedfunctionsListBox.ItemsData ItemData_selected];
                    app.AvailablefunctionsListBox.Items(idx) = [];
                    app.AvailablefunctionsListBox.ItemsData(idx) = [];
                end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                Items = app.SelectedfunctionsListBox.Items;
                ItemsData = app.SelectedfunctionsListBox.ItemsData;
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                pos = find(idx);
                if pos > 1
                    range = 1:numel(Items);
                    range(pos) = pos -1 ;
                    range(pos -1) = pos;
                    app.SelectedfunctionsListBox.Items = Items(range);
                    app.SelectedfunctionsListBox.ItemsData = ItemsData(range);
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                Items = app.SelectedfunctionsListBox.Items;
                ItemsData = app.SelectedfunctionsListBox.ItemsData;
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                pos = find(idx);
                if pos < numel(Items)
                    range = 1:numel(Items);
                    range(pos) = pos + 1;
                    range(pos + 1) = pos;
                    app.SelectedfunctionsListBox.Items = Items(range);
                    app.SelectedfunctionsListBox.ItemsData = ItemsData(range);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.createFuncList
        end

        % Button pushed function: SetOptionsButton
        function SetOptionsButtonPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if ~isempty(ItemData_selected) && ~isempty(ItemData_selected.opts)
                opts = app.Pipe.setOpts(ItemData_selected.Name);
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                app.SelectedfunctionsListBox.ItemsData(idx).opts = opts;
                if ~isequaln(opts, ItemData_selected.opts)
                    app.SelectedfunctionsListBox.Items(idx) = strrep(app.SelectedfunctionsListBox.Items(idx), '(!)', '(custom opts!)');
                end
            end
        end

        % Button pushed function: CreatePipelineButton
        function CreatePipelineButtonPushed(app, event)
            if isempty(app.SelectedfunctionsListBox.Items)
                uialert(app.UIFigure, 'You have to select at least one function to create a Pipeline!', 'Warning!', 'Modal',true);
                return
            end
            ItemsData = app.SelectedfunctionsListBox.ItemsData;
            for i = 1:numel(ItemsData)
                className = ItemsData(i).ClassName;
                funcName = ItemsData(i).Name;
                if isempty(ItemsData(i).opts)
                    app.Pipe.addTask(className, funcName);
                else
                    app.Pipe.addTask(className, funcName, ItemsData(i).opts);
                end
            end
            app.CallingApp.PipelineObj = app.Pipe;
            app.CallingApp.ConfigureNewPipelineButton.Enable = 'on';
            app.CallingApp.displayPipeInfo(true);
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed2(app, event)
            classSel = app.AddfunctiontoDropDown.Value;
            FuncSel = app.AvailablefunctionsListBox.Value;
            if strcmp(FuncSel.Name, '----------')
                return
            end
            idx = strcmp({app.AvailablefunctionsListBox.ItemsData.Name}, FuncSel.Name);
            FuncName = app.AvailablefunctionsListBox.Items(idx);
            newItem = [classSel ': ' FuncName{:}];
            newItemData = FuncSel; newItemData.ClassName = classSel;
            if ~ismember(newItem, app.SelectedfunctionsListBox.Items)
                app.SelectedfunctionsListBox.Items = [app.SelectedfunctionsListBox.Items newItem];
                app.SelectedfunctionsListBox.ItemsData = [app.SelectedfunctionsListBox.ItemsData newItemData];
            end
        end

        % Button pushed function: RemoveItemButton
        function RemoveItemButtonPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                app.SelectedfunctionsListBox.Items(idx)=[];
                app.SelectedfunctionsListBox.ItemsData(idx)=[];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PipelineConfigurationLabel = uilabel(app.UIFigure);
            app.PipelineConfigurationLabel.Text = 'Pipeline Configuration';
            app.AvailablefunctionsListBoxLabel = uilabel(app.UIFigure);
            app.AvailablefunctionsListBoxLabel.Text = 'Available functions:';
            app.AvailablefunctionsListBox = uilistbox(app.UIFigure);
            app.AvailablefunctionsListBox.Items = {};
            app.AvailablefunctionsListBox.Value = {};
            app.SelectedfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedfunctionsListBoxLabel.Text = 'Selected functions:';
            app.SelectedfunctionsListBox = uilistbox(app.UIFigure);
            app.SelectedfunctionsListBox.Items = {};
            app.SelectedfunctionsListBox.Value = {};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'icon_arrow_up.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'icon_arrow_dwn.png';
            app.Button_4.Text = '';
            app.CreatePipelineButton = uibutton(app.UIFigure, 'push');
            app.CreatePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePipelineButtonPushed, true);
            app.CreatePipelineButton.Text = 'Create Pipeline';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetOptionsButton = uibutton(app.UIFigure, 'push');
            app.SetOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetOptionsButtonPushed, true);
            app.SetOptionsButton.Text = {'Set'; ' Options'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'* items with (!) have optional parameters.'; 'Click in "Set Options" to change them.'};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed2, true);
            app.AddButton.Text = '>>Add>>';
            app.AddfunctiontoDropDownLabel = uilabel(app.UIFigure);
            app.AddfunctiontoDropDownLabel.Text = 'Add function to:';
            app.AddfunctiontoDropDown = uidropdown(app.UIFigure);
            app.AddfunctiontoDropDown.Items = {'Select...'};
            app.AddfunctiontoDropDown.Value = 'Select...';
            app.RemoveItemButton = uibutton(app.UIFigure, 'push');
            app.RemoveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveItemButtonPushed, true);
            app.RemoveItemButton.Text = {'Remove'; ' Item'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineConfig_dlgBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end