classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputMenu                    matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        ExportasPNGMenu               matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        AxissettingsMenu              matlab.ui.container.Menu
        ColorMenu                     matlab.ui.container.Menu
        ROIinmainfigureDropDownLabel  matlab.ui.control.Label
        ROIinmainfigureDropDown       matlab.ui.control.DropDown
        PlottypeButtonGroup           matlab.ui.container.ButtonGroup
        lineButton                    matlab.ui.control.RadioButton
        boxplotButton                 matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFigs % Handle to Summary figures.
        mainAx % Handle to axis in main figure;
        scatData % Structure containing the metaData of each item from the scatter plots in the summary figures.
        mainAxMetaData % MetaData for each scatter point in the main figure;
        dcm_obj % DataCursor mode object for datatips in Main Figure.        
        cmp % current mouse position. This property is updated by the WindowButtonMotionFcn of the main fig.
    end
    
    methods (Access = private)
        
        function createSummaryFigs(app)
            % This method creates figures with line + scatter plots. A maximum of 8 plots
            % is allowed per figure.
            
            
            % Create first figure:
            app.summaryFigs = figure('Name', ['Summary figure #' num2str(1)], 'NumberTitle', 'off',...
                'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing);
            movegui(app.summaryFigs(1), 'southeast');
            cnt = 1; % Axes counter.
            % Create a plot for each observation:
            % Loop over the number of groups:
            gList = unique(app.statsObj.stats_data(:,1));
            colList = cool(numel(gList));
            app.scatData = struct('handles',[],'metaData',{});
            for ii = 1:numel(app.statsObj.obs_list)
                % Create axis:
                s = subplot(4,2,cnt,"replace",'Parent',app.summaryFigs(end), 'Tag', app.statsObj.obs_list{ii});
                hold(s,"on")
%                 scatHandles = [];
%                 metaData_list = struct();
                cnt_scat = 1;
                for j = 1:numel(gList)
                    [acqIndx,data, metaData] = app.getData(gList{j}, app.statsObj.obs_list{ii});
                    if isempty(acqIndx)
                        continue
                    end
                    % Create errorbar plot (default):
                    % Calculate average and STD of data by acqIndx and create errorbar plot:
                    avg = arrayfun(@(x) mean(data(acqIndx == x), 'omitnan'),unique(acqIndx));
                    stDev = arrayfun(@(x) std(data(acqIndx == x),0,"all", 'omitnan'),unique(acqIndx));
                    errorbar(s,unique(acqIndx),avg,stDev, 'Tag', ['errG' num2str(j)], 'Color', colList(j,:));
                    
                    % Create scatter plot:
                    scat = scatter(s,acqIndx,data,'filled');
                    set(scat, 'MarkerEdgeColor', colList(j,:), 'MarkerFaceColor', [.7 .7 .7], 'Tag', ['scatG' num2str(j)]);
                    %
                    app.scatData(ii).handles(cnt_scat) = scat;
                    app.scatData(ii).metaData{cnt_scat} = metaData;
                    cnt_scat = cnt_scat + 1;
                end
                % Change some axis properties:
                s.XLim = [s.XLim(1)-1 s.XLim(2)+1];
                s.XTick = unique(acqIndx);
                s.Color = [.95 .95 .95];
%                 app.scatData(ii).handles = scatHandles;
%                 app.scatData(ii).metaData = metaData_list;
                title(s,app.statsObj.obs_list{ii}, 'Interpreter',"none");
                hold(s,"off")
                cnt = cnt+1;
                if cnt > 8
                    cnt = 1;
                    if ii<numel(app.statsObj.obs_list)
                        app.summaryFigs = vertcat(app.summaryFigs,...
                            figure('Name', ['Summary figure #' num2str(length(app.summaryFigs)+1)], 'NumberTitle', 'off',...
                            'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing));
                        movegui(app.summaryFigs(end), 'southeast');
                    end
                end
            end
            % Show figures:
            arrayfun(@(x) set(x, 'Visible', 'on'), app.summaryFigs);
        end
                
        function [x,y,mD] = getData(app,groupID,obsID)
            % This function retrieves the data and  acquisition index
            % from the "stats_data" property of app.statsObj for all
            % recordings from a given group ("groupID") for a given
            % observation ("obsID").
            % Inputs:
            %   groupID (char): name of experimental group;
            %   obsID (char): name of observation.
            % Outputs:
            %   x (num. array): list of the acquisition indexes for
            %   each data point.
            %   y (num. array): data.
            %   mD(cell array): metaData of "data".
            x=[];y=[];mD=[];
            
            idxG = strcmp(groupID,app.statsObj.stats_data(:,1));
            indxObs = cellfun(@(x) find(strcmp(obsID,x)), app.statsObj.stats_data(idxG,...
                strcmp(app.statsObj.stats_data_headers,'observationID')),'UniformOutput',false);
            if all(cellfun(@isempty, indxObs))
                return
            end
            mD = app.statsObj.stats_data(idxG,ismember(app.statsObj.stats_data_headers,...
                {'groupID', 'SubjectID', 'AcquisitionID', 'ModalityID', 'RecStartDateTime', 'data', 'AcquisitionIndx'}));
            % Drop rows with missing observation:            
            b_isEmpty = cellfun(@isempty,indxObs);
            mD(b_isEmpty,:) = [];
            indxObs(b_isEmpty)= [];
            % Get Acquisition index and data point:
            y = single.empty(0,size(mD,1));
            x=y;
            for k = 1:size(mD,1)
                x(k) = mD{k,end};
                y(k) = mD{k,end-1}{indxObs{k}};
            end
            % Drop last column of data:
            mD(:,end-1:end) = [];
            % Sort data and metadata using ascending order of the
            % acquisition indices:
            [x,idx] = sort(x);
            y = y(idx);
            mD = mD(idx,:);
            % Add ObsID to metaData:
            mD = horzcat(mD, repmat({obsID},size(mD,1),1));
            
        end
                
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(~,~,~)
            % EMPTY!%            
        end
        
        function txt = showDataTip(app,~,evnt)
            % Callback to show object's information in datatip.            
            
            % If the user clicke on a scatter plot dot:
            if isa(evnt.Target, 'matlab.graphics.chart.primitive.Scatter')
                indx = str2double(erase(evnt.Target.Tag, 'scatG'));
                info = app.mainAxMetaData.metaData{indx}(evnt.Position(1),:);
                info = [info(1:end-1), {evnt.Position(2)}];
                %                txt = sprintf(' \\color[rgb]{%0.3f %0.3f %0.3f}\\bf\n',...
                %                    evnt.Target.CData(1), evnt.Target.CData(2), evnt.Target.CData(3));
                %                txt =[txt,sprintf('GroupID: %s\nSubjectID: %s\nAcq.ID: %s\nMod.ID: %s\nRec.DateTime: %s\nValue: %5.3f',...
                %                    info{:})];
                txt = sprintf('Point:\nGroupID: %s\nSubjectID: %s\nAcq.ID: %s\nMod.ID: %s\nRec.DateTime: %s\nValue: %0.3f',...
                    info{:});
            elseif isa(evnt.Target, 'matlab.graphics.chart.primitive.ErrorBar')
                txt = sprintf('Group Line\nX: %d\nY: %0.3f\nError: %0.3f',...
                    evnt.Position(1), evnt.Position(2), evnt.Target.YPositiveDelta(evnt.Position(1)));            
            else
                txt = sprintf('Single Line\nX: %d\nY: %0.3f', evnt.Position(1), evnt.Position(2));
            end
            
        end
        function getMousePos(app,~,~)
            % This callback continuously tracks the mouse position inside the main axis.
            cmp = get(app.mainAx, 'CurrentPoint');
            app.cmp = cmp(1,1:2);            
        end
        function enterFcn(app,src,evnt)
            % This callback overlays a line plot to connect the scatter plot data from
            %  single subjects.            
            
            set(app.mainFig, 'Pointer', 'crosshair')                        
%             % Find scatter plot dot that is closest to the pointer:
%             dat = arrayfun(@(x) sqrt(abs(x.XData - app.cmp(1)).^2 + abs(x.YData - app.cmp(2)).^2), app.mainAxMetaData.handles, 'UniformOutput',false);
%             for i = 1:numel(dat)
%                 indx_pt(i) = find(dat{i} == min(dat{i}));
%                 dist_dat(i) = dat{i}(indx_pt(i));
%             end
%             indx_g = find(dist_dat == min(dist_dat));
%             indx_pt = indx_pt(indx_g);
%             disp(indx_pt)
%             % Plot line over scatterplot:
%             app.mainAxMetaData.handles(indx_g);           
%             idx_subj = strcmp(app.mainAxMetaData.metaData(indx_g).out(:,2), app.mainAxMetaData.metaData(indx_g).out{indx_pt,2});
%             hold(app.mainAx, 'on')
%             plot(app.mainAx,app.mainAxMetaData.handles(indx_g).XData(idx_subj),app.mainAxMetaData.handles(indx_g).YData(idx_subj),...
%                 'k-','LineWidth',2, 'Tag', 'tmpPlot');
%             hold(app.mainAx, 'off');%             
            
        end
    
        function exitFcn(app, src,evnt)
            set(app.mainFig, 'Pointer', 'arrow')
%             h = findobj(app.mainAx, 'Tag', 'tmpPlot');
%             delete(h);
        end
        
    
        function clickMainAx(app, src, ~)
            % Custom callback that shows/hides individual line plots when
            % the User clicks over a scatter plot dot.
            
            if strcmp(get(src, 'Pointer'), 'arrow')
                return
            end
            
            click = get(src, 'SelectionType');
            
            if strcmp(click, 'alt') 
                h = findobj(app.mainAx, 'Tag', 'tmpPlot');
                delete(h);
                return
            end
           % Find scatter plot dot that is closest to the pointer:
           % First, find closest X position from current click coordinates:
           x_click = round(app.cmp(1));
           if x_click> max(app.mainAxMetaData.handles(1).XData)
               x_click = max(app.mainAxMetaData.handles(1).XData);
           elseif x_click<min(app.mainAxMetaData.handles(1).XData)
               x_click = min(app.mainAxMetaData.handles(1).XData);
           end
            dat = arrayfun(@(x) sqrt(abs(app.cmp(1)-x.XData(x_click))^2 + abs(app.cmp(2)-x.YData(x_click))^2),...
                app.mainAxMetaData.handles, 'UniformOutput',true);            
            [~,indx_g] = min(dat);                        
            % Plot line over scatterplot:
            app.mainAxMetaData.handles(indx_g);           
            idx_subj = strcmp({app.mainAxMetaData.metaData{indx_g}{:,2}}, app.mainAxMetaData.metaData{indx_g}{x_click,2});
            hold(app.mainAx, 'on')
            plot(app.mainAx,app.mainAxMetaData.handles(indx_g).XData(idx_subj),app.mainAxMetaData.handles(indx_g).YData(idx_subj),...
                'k-','LineWidth',2, 'Tag', 'tmpPlot');
            hold(app.mainAx, 'off');             
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
        function b_in_bounds = getBounds(app, cp)
            % GETBOUNDS verifies if the current position (cp) of the mouse
            % cursor is inside the boundaries
            % of the main axis.
            
            % Input:
            % cp (1x2 num. array): X,Y mouse cursor position.           
            % Output:
            % b_in_bounds (bool) : true, if cursor is inside the image,
            % false, if is not.
            
            % get axis limits:
            x_lims = get(app.mainAx, 'XLim');
            y_lims = get(app.mainAx, 'YLim');
            
            % Check if cursor is inside the axis limits:
            b_in_bounds = (cp(1) >= x_lims(1)) && ...
                (cp(1) <= x_lims(2)) && ...
                (cp(2) >= y_lims(1)) && ...
                (cp(2) <= y_lims(2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            
            %
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'northwest');
            app.UIFigure.Visible = 'on';
            app.statsObj = statsObj;
            % Populate dropdown list:
            app.ROIinmainfigureDropDown.Items = app.statsObj.obs_list;
            % Create main figure:
            app.mainFig = figure('Name', ['Main - ' app.ROIinmainfigureDropDown.Value], 'NumberTitle','off',...
                'Position', [0 0 880 420],'Visible', 'off', 'WindowButtonDownFcn', @app.clickMainAx,...
                'WindowButtonMotionFcn',@app.getMousePos, 'CloseRequestFcn', @app.DoNothing);
            app.mainAx = axes('Parent', app.mainFig, 'Position',[.1 .12 .8 .8], 'Units',"normalized");
            % Create summary figures:
            app.createSummaryFigs;
            % Show Figures and app:
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            % Set some axis properties of mainAx:
            app.mainAx.Title.Interpreter = 'none';
            app.mainAx.XLabel.String = 'Acquisition index';
            app.mainAx.YLabel.String = 'Value';
            app.mainAx.XLim = [(app.mainAx.XLim(1)-1), (app.mainAx.XLim(2)+1)];
            app.mainAx.XTick = app.summaryFigs(1).Children(1).XTick;
            app.mainAx.Color = [.95 .95 .95];
            gNames = cellfun(@(x) x{1,1}, app.scatData(1).metaData, 'UniformOutput', false);
            legend(app.mainAx, app.scatData(1).handles,gNames,'AutoUpdate',"off", 'Location',"bestoutside");
           
            % Set Data cursor info:
            app.dcm_obj = datacursormode(app.mainFig);
            set(app.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showDataTip, 'Interpreter', 'none');
            % Make it visible:
            app.mainFig.Visible = 'on';
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.mainFig)
            delete(app.summaryFigs)
            delete(app)
        end

        % Value changed function: ROIinmainfigureDropDown
        function ShowPlotInMainFig(app, event)
            % This callback makes a copy of selected ROI plot from the
            % summaryFigs into the main fig axis
            idx_plot = strcmp(app.ROIinmainfigureDropDown.Value, arrayfun(@(x) x.metaData{1}{1,end},app.scatData,'UniformOutput',false));
            cla(app.mainAx);
            copyobj(app.scatData(idx_plot).handles(1).Parent.Children,app.mainAx);
            app.mainAx.Title.String = app.scatData(idx_plot).metaData{1}{1,end};
            app.mainAxMetaData = app.scatData(idx_plot);
            % Add PointerBehaviour callbacks to main axis:            
            pointerBehaviour.enterFcn = @app.enterFcn;
            pointerBehaviour.traverseFcn = [];
            pointerBehaviour.exitFcn = @app.exitFcn;
            iptSetPointerBehavior(app.mainAx, pointerBehaviour);           
            iptPointerManager(app.mainFig, 'enable');
            % Update legend:            
            gNames = cellfun(@(x) x{1,1}, app.scatData(idx_plot).metaData, 'UniformOutput', false);
            legend(app.mainAx, app.scatData(idx_plot).handles,gNames,'AutoUpdate',"off", 'Location',"bestoutside")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Longitudinal data Plotter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputMenu = uimenu(app.UIFigure);
            app.OutputMenu.Text = 'Output';
            app.SaveasMenu = uimenu(app.OutputMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.ExportasPNGMenu = uimenu(app.OutputMenu);
            app.ExportasPNGMenu.Text = 'Export as.PNG';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AxissettingsMenu = uimenu(app.SettingsMenu);
            app.AxissettingsMenu.Text = 'Axis settings';
            app.ColorMenu = uimenu(app.SettingsMenu);
            app.ColorMenu.Text = 'Color';
            app.ROIinmainfigureDropDownLabel = uilabel(app.UIFigure);
            app.ROIinmainfigureDropDownLabel.Text = 'ROI in main figure';
            app.ROIinmainfigureDropDown = uidropdown(app.UIFigure);
            app.ROIinmainfigureDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowPlotInMainFig, true);
            app.PlottypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottypeButtonGroup.Title = 'Plot type';
            app.lineButton = uiradiobutton(app.PlottypeButtonGroup);
            app.lineButton.Text = 'line';
            app.lineButton.Value = true;
            app.boxplotButton = uiradiobutton(app.PlottypeButtonGroup);
            app.boxplotButton.Text = 'boxplot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end